classdef GUI1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlottingSwitch              matlab.ui.control.RockerSwitch
        PlottingSwitchLabel         matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        Defaults                    matlab.ui.control.Button
        directory                   matlab.ui.control.EditField
        DirectoryEditFieldLabel     matlab.ui.control.Label
        argamma                     matlab.ui.control.NumericEditField
        keVEditField_3Label         matlab.ui.control.Label
        fgamma2                     matlab.ui.control.NumericEditField
        keVEditField_2Label         matlab.ui.control.Label
        refIndex                    matlab.ui.control.NumericEditField
        IndexValueEditFieldLabel    matlab.ui.control.Label
        fgamma1                     matlab.ui.control.NumericEditField
        keVEditFieldLabel           matlab.ui.control.Label
        refCurrent                  matlab.ui.control.NumericEditField
        CurrentValueEditFieldLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        
        function spedat = readspe(app,fname)
            data = struct;
            oneline = fileread(fname); % import as character vector 
            multiline =  transpose(strsplit(oneline, "\n")); % split by \n
            
            %% Pull Timing Information
            timing = split(multiline(10),' ');
            data.livetime = str2num(timing{1,1});
            data.realtime = str2num(timing{2,1});
            
            %% Pull Energy Fit
            
            e_idx = find(contains(multiline,'ENER'))+1;
            fit = split(multiline(e_idx),' ');
            intercept = str2num(fit{1,1});
            slope = str2num(fit{2,1});
            
            channels = 1:8192;
            data.channels = channels;
            data.energy = (slope * channels + intercept)';
            data.slope = slope;
            data.intercept = intercept;
            
            %% Pull Date and Time
            t_idx = find(contains(multiline,'DATE_MEA'))+1;
            datestring = strip(string(multiline(t_idx)));
            data.time = datetime(datestring,'InputFormat','MM/dd/yyyy HH:mm:ss');
            %% Pull Data
            idxstart = find(contains(multiline,'DATA'))+2;
            idxend = idxstart+8191;
            counts = multiline(idxstart:idxend,1);
            data.counts = str2double(counts);
            
            %% Sending Data
            spedat = data;
            % This contains: livetime, realtime, energy, counts
        end
        
        function [AUC,error,realtime,livetime] = get_AUC(app,spectrum, peaksOI)
            num_peaks = length(peaksOI);
            AUC = zeros(1,num_peaks);
            error = zeros(1,num_peaks);
            unbackgrounded = spectrum.counts;
            hold on
            trimmedBackground = baseliner(app,unbackgrounded(71:end)); % removing low energy drop off
            filler = zeros(70,1);
            background = vertcat(filler, trimmedBackground);
            counts = unbackgrounded-background;
                
            if app.PlottingSwitch.Value == 1
                plot(unbackgrounded,'k')
            end
            
            for i = 1:num_peaks
                meanCN = peaksOI(i);
                
            % sub-protocol to adjust mean_cn to reflect actual location of peak
            % Checks to make sure peak actually exists.
                searchWidth = 20; % 20 channel width search
                range = (meanCN-searchWidth):(meanCN+searchWidth);
                [maxValue,maxIndex]= max(counts(range));
                
                % adjust peak location center
                if maxValue > 10
                    meanCN = range(maxIndex);
                end
                halfMax = counts(meanCN)/2;
                
                if app.PlottingSwitch.Value == 1
                    scatter(meanCN, -10)
                end
                
                mask = counts((meanCN-15):(15+meanCN))>= halfMax;
                FWHM = sum(mask);
                
                if counts(meanCN) > 100
                    idxHigh = floor(meanCN+4*FWHM);
                    idxLow = floor(meanCN-3*FWHM);
                    idxRng = [idxLow:idxHigh];
                    errorBelow = std(counts(round(idxLow-FWHM):idxLow));
                    errorAbove = std(counts(idxHigh:round(idxHigh+FWHM)));
                    stdev = (errorBelow+errorAbove)/2;
            
                else
                    idxHigh = meanCN+7;% 20 for csv
                    idxLow = meanCN-7;% 20 for csv
                    idxRng = [idxLow:idxHigh];
                    errorBelow = std(counts(idxLow-5:idxLow));
                    errorAbove = std(counts(idxHigh:idxHigh+5));
                    stdev = (errorBelow+errorAbove)/2;
                end
            
                
            %     x = [round(meanCN-4.5*FWHM) round(meanCN+4.5*FWHM)];
            %     y = [low_counts high_counts];
            %     fit = polyfit(x,y,1);
            %     background = polyval(fit,idxRng);
            %     
            
            % remove all negative values from the region
                subCounts = counts(idxRng);
                subCounts(subCounts<0) = 0;
                AUC(1,i) = sum(subCounts);
                error(1,i) = sqrt(abs(AUC(1,i)) + (2*stdev)^2);
                
                %plot(idxRng,counts(idxRng) - background');
                %plot(idxRng,background)
                
                if app.PlottingSwitch.Value == 1
                    plot(idxRng,counts(idxRng));
                end
                
                
            end
            livetime = spectrum.livetime;
            realtime = spectrum.realtime;
        end
        
        function bkg = baseliner(app,origSpec)
            y = log(log(sqrt(origSpec+1)+1)+1);
            lambda = 10^5;
            ratio = 10^-2;
        
         %   lambda = 10^6; %for csv
         %   ratio = 10^-1; %for csv
            
            N = length(y);
            D = diff(speye(N),2);
            H = lambda*D'*D;
            w = ones(N,1);
            while true
                W = spdiags(w,0,N,N);
                C = chol(W+H);
                z = C \ (C'\(w.*y));
        
                d = y-z;
                dn = d(d<0);
                m = mean(dn);
                s = std(dn);
                wt = 1./(1+exp(2*(d-(2*s-m))/s));
                if norm(w-wt)/norm(w) < ratio, break
                end
                w = wt;
        %         hold on
        %         plot(y)
        %         plot(z,'LineWidth',2)
        %         hold off
        %         clf('reset')
        %        
            end
            hold on
            
            if app.PlottingSwitch.Value == 1
                plot(y)
                plot(z,'LineWidth',2)
                clf('reset')
                hold on
            end
            
            bkg = (exp(exp(z)-1)-1).^2-1;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Defaults
        function DefaultsPushed(app, event)
            % write in whats supposed to happen
            app.refCurrent.Value = 70;
            app.refIndex.Value = 5;
            app.directory.Value = '/Users/tm98/Downloads/3.1.23';
            
            app.fgamma1.Value = 379;
            app.fgamma2.Value = 680;
            app.argamma.Value = 2658;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Input Currents
            disp(app.PlottingSwitch.Value)
            folderDir = app.directory.Value;
            current = app.refCurrent.Value; % single current value in nA
            current_index = app.refIndex.Value; % single value of which file corresponds to this current
            
            % which number run does each current correspond to
            % chronological order please
            
            %% File set up
            if app.PlottingSwitch.Value == 1
               set(0,'DefaultFigureWindowStyle','docked')
            end
            
            d = struct2cell(dir([fullfile(folderDir),'/*.spe']));
            nameList = d(1,:);
            
            file_count = numel(nameList);
            
            %% Information on peaks of interest
            % peaksOI = [4200 6100 2694];% chlorine channels
            peaksOI = [app.fgamma1.Value app.fgamma2.Value app.argamma.Value]; % fluorine channels
            %peaksOI = [1342, 2425, 9478]; %for CSV file
            
            aucGroup = [];
            errorGroup = [];
            datetimeGroup = [];
            realtimes = [];
            livetimes = [];
            
            f = waitbar(0,'Please wait...');
           
            %% Pull Count Rates for Peaks Of Interest
            for i=1:file_count

                
                if app.PlottingSwitch.Value == 1
                    p(1:file_count) = figure;
                    p(i);
                    hold on
                end


                file = string(fullfile(folderDir, nameList(i)));
                spectrum = readspe(app,file);
                [AUC, error, realtime, livetime] = get_AUC(app,spectrum,peaksOI);
            
                aucGroup = [aucGroup ; AUC];
                errorGroup = [errorGroup;error];
                realtimes = [realtimes; realtime];
                livetimes = [livetimes; livetime];
                datetimeGroup = [datetimeGroup ; spectrum.time];
            
                if app.PlottingSwitch.Value == 1
                    title(nameList(i));
                end
                message = strcat ('Processing:   ', nameList(i));
                waitbar(i./file_count,f, message);
            end
            
            %% Continuing Analysis
            fluorine1 = aucGroup(:,1);
            f1Error = errorGroup(:,1);
            fluorine2 = aucGroup(:,2);
            f2Error = errorGroup(:,2);
            argon = aucGroup(:,3);
            arError = errorGroup(:,3);
            
            
            % sum fluorine counts and error
            totalF = fluorine1+fluorine2;
            totalF_err = sqrt(f1Error.^2 + f2Error.^2)./totalF; %relative err
            
            % divide argon counts by live time
            argonError = (arError./argon); %relative err
            
            % calculate counts/uC
            denominator = current.*livetimes(current_index).*argon;
            norm_C_uC = 1000.*totalF.*argon(current_index)./denominator;
            norm_C_uC_err = norm_C_uC.*sqrt(argonError.^2 + totalF_err.^2); % absolute error
            
            
            %% Formatting Output
            % filename, livetime, argon counts, Ar norm, Ar norm err
            sz = [file_count 7];
            vartypes = ["string" "datetime" "double" "double" "double" "double" "double"];
            varnames = ["Sample" "Date Time" "Realtime (s)" "Total Fluorine Counts" "770 keV counts" "Ar-Norm counts/uC" "error"];
            output = table('Size',sz, 'VariableTypes', vartypes, 'VariableNames', varnames);
            output.(1) = nameList';
            output.(2) = datetimeGroup;
            output.(3) = realtimes;
            output.(4) = totalF;
            output.(5) = argon;
            output.(6) = norm_C_uC;
            output.(7) = norm_C_uC_err;
            splitDir = split(folderDir,{'\' '/'});
            properFileName = [splitDir{end,1} '_F_results.xlsx'];
            file = string(fullfile(folderDir, properFileName));
            
            currentDate = date;
            currentVersion = "Smooth-Fit Background V1.1";
            dateVersionLine = strcat(string(currentDate), " ", currentVersion);
            writematrix(dateVersionLine,file,'Sheet',1,'Range','A1')
            writetable(output,file,'Sheet',1, 'Range','A2');
            waitbar(1,f,'Complete! Find Report in directory.');
            clc
            clear
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentValueEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentValueEditFieldLabel.Text = 'Current Value';
            app.refCurrent = uieditfield(app.UIFigure, 'numeric');
            app.refCurrent.Limits = [0 Inf];
            app.keVEditFieldLabel = uilabel(app.UIFigure);
            app.keVEditFieldLabel.Text = '109 keV';
            app.fgamma1 = uieditfield(app.UIFigure, 'numeric');
            app.fgamma1.RoundFractionalValues = 'on';
            app.IndexValueEditFieldLabel = uilabel(app.UIFigure);
            app.IndexValueEditFieldLabel.Text = {'Index Value'; ''};
            app.refIndex = uieditfield(app.UIFigure, 'numeric');
            app.refIndex.Limits = [0 Inf];
            app.refIndex.RoundFractionalValues = 'on';
            app.keVEditField_2Label = uilabel(app.UIFigure);
            app.keVEditField_2Label.Text = '197 keV';
            app.fgamma2 = uieditfield(app.UIFigure, 'numeric');
            app.fgamma2.RoundFractionalValues = 'on';
            app.keVEditField_3Label = uilabel(app.UIFigure);
            app.keVEditField_3Label.Text = '770 keV';
            app.argamma = uieditfield(app.UIFigure, 'numeric');
            app.argamma.RoundFractionalValues = 'on';
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = {'Directory'; ''};
            app.directory = uieditfield(app.UIFigure, 'text');
            app.Defaults = uibutton(app.UIFigure, 'push');
            app.Defaults.ButtonPushedFcn = createCallbackFcn(app, @DefaultsPushed, true);
            app.Defaults.Text = {'Load Defaults'; ''};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PlottingSwitchLabel = uilabel(app.UIFigure);
            app.PlottingSwitchLabel.Text = 'Plotting';
            app.PlottingSwitch = uiswitch(app.UIFigure, 'rocker');
            app.PlottingSwitch.ItemsData = [0 1];
            app.PlottingSwitch.Value = 0;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end